classdef AudioApp_AryanVarunYuvraj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        VolumeKnob               matlab.ui.control.Knob
        VolumeKnobLabel          matlab.ui.control.Label
        Merge                    matlab.ui.control.CheckBox
        merge2                   matlab.ui.control.Table
        PlaybackSpedSlider       matlab.ui.control.Slider
        PlaybackSpedSliderLabel  matlab.ui.control.Label
        ShuffleCheckBox          matlab.ui.control.CheckBox
        RepeatCheckBox           matlab.ui.control.CheckBox
        PickedSongLabel          matlab.ui.control.Label
        Button_2                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        songIndex = -1;
        songIndex2 = -2;
        lastIndex = -2;
        player = -3;
        titles = ["song1 - Key C BPM 145"; "song2 - Key Cm BPM 134"; "song3 - Key Cm BPM 120"; 
            "song4 - Key C BPM 95"; "song5 - Key A BPM 110"; "song6 - Key Am BPM 125"; "song7 - Key F BPM 103";
            "song8 - Key Cm BPM 118"; "song9 - Key A BPM 150"; "song10 - Key B BPM 110"];
        songTable;
        fileNames;
        playOrPause = false;
        repeatBool = false;
        shuffleBool = false;
        currentSong;
        fs;
        playbackSpeed;
        checkMerge = false;
        SMerge;
        fsMerge = -1;
        volume = 1;
    end

    
    methods (Access = public)
        
        function [SMerge, fsMerge] = merged(app, currentSong1, fs1, currentSong2, fs2)
        
        lengthOfY = size(currentSong1, 1); % get number of elements in matrix for all wav files 
        lengthOfZ = size(currentSong2, 1);

        lengthOfAvg = max(lengthOfY, lengthOfZ); %number of elements for average

        S = zeros(lengthOfAvg, size(currentSong1, 2)); %IMPORTANT!! - zero padding allows to estimate amplitude of n frequecies correctly...
        %makes sure that all songs when mixed play at the same amplitude i.e.
        %merged song has average loudness of all wav files used
        S(1:lengthOfY, :) = currentSong1;              
        S(1:lengthOfZ, :) = S(1:lengthOfZ, :) + currentSong2;
        %S(1:lengthOfX, :) = S(1:lengthOfX, :) + x; adding all wav files together with right dimensions
        maxValue = max(abs(S(:))); %max value for matrix 

        SMerge = S / maxValue; %merged wav files divided by max to get final audio file
        fsMerge = fs1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            app.fileNames = app.titles + ".wav";
            app.songTable = table(app.titles);
            app.UITable.Data = app.songTable;
            app.merge2.Data = app.songTable;

            app.merge2.Visible = "off";
            app.UIAxes_2.Visible = "off";
            app.UIAxes_3.Visible = "off";
            app.UIAxes.XColor = "white";
            app.UIAxes_2.XColor = "white";
            app.UIAxes_3.XColor = "white";
            app.UIAxes.YColor = "white";
            app.UIAxes_2.YColor = "white";
            app.UIAxes_3.YColor = "white";
            app.UIAxes.ColorOrder = [0.11373  0.72549  0.32941];
            app.UIAxes_2.ColorOrder = [0.11373  0.72549  0.32941];
            app.UIAxes_3.ColorOrder = [0.11373  0.72549  0.32941];
            
            channelID = 1940163;
            userAPIKey = 'AP3YO1V4OPUR9XK0';

            url = sprintf('https://api.thingspeak.com/channels/%s/feeds.json?api_key=%s', num2str(channelID), userAPIKey);
            webwrite(url, weboptions('RequestMethod','delete'));


        end

        % Button pushed function: Button
        function PlayStatus(app, event)
            if app.repeatBool || app.shuffleBool
                return;
            else
                if app.songIndex == -1
                    return;
                end
                if ~app.playOrPause && app.lastIndex ~= app.songIndex
                     if ~app.checkMerge  
                        [currentSong, fs] = audioread(app.fileNames(app.songIndex));
                        app.player = audioplayer(currentSong, fs);

                        info = audioinfo(app.fileNames(app.songIndex));
                        
                        t = 0:seconds(1/fs):seconds(info.Duration);
                        t = t(1:end-1);
                        app.UIAxes.YLim = [-1.5, 1.5];

                        plot(app.UIAxes,t,currentSong);
                     else
                        if app.songIndex2 > 0
                            [currentSong1, fs1] = audioread(app.fileNames(app.songIndex));
                            [currentSong2, fs2] = audioread(app.fileNames(app.songIndex2));
                            [SMerge, fsMerge] = merged(app, currentSong1, fs1, currentSong2, fs2); 

                            app.player = audioplayer(SMerge, fsMerge);
                        end
                     end
                    if app.player.isplaying == false
                        app.lastIndex = app.songIndex;
                        app.playOrPause = true;
                        app.Button.Text = "⏸";
                        app.player.play;
                    end
                elseif ~app.playOrPause && app.lastIndex == app.songIndex
                    app.player.resume;
                    app.playOrPause = true;
                    app.Button.Text = "⏸";
                elseif app.playOrPause
                    app.player.pause;
                    app.playOrPause = false;
                    app.Button.Text = "▶";
                end
           end
        end

        % Cell selection callback: UITable
        function pickedSong(app, event)
            app.songIndex = event.Indices(1);

            app.PickedSongLabel.Text = "Currently Playing: " + app.songTable(app.songIndex, 1).Variables;

            channelID = 1940163;
            userAPIKey = 'AP3YO1V4OPUR9XK0';

           
            readKey = '7YYXWLHC6KULK76B';
            writeKey =  'E80CBWVDWY00LEOM';

            pause(2)
            thingSpeakWrite(channelID, 'WriteKey', writeKey, 'Fields', [1], 'Values', app.songIndex);
            pause(2)

            if app.player ~= -3 && app.player.isplaying == false
                app.playOrPause = false;
                app.Button.Text = "▶";
            end
        end

        % Cell selection callback: merge2
        function merge2CellSelection(app, event)
            app.songIndex2 = event.Indices(1);
     
            if app.songIndex2  > 0
                if app.songIndex > 0
                    app.PickedSongLabel.Text = "Currently Playing: " + app.songTable(app.songIndex, 1).Variables + " & "  + app.songTable(app.songIndex2, 1).Variables;
                    [currentSong1, fs1] = audioread(app.fileNames(app.songIndex));
                    [currentSong2, fs2] = audioread(app.fileNames(app.songIndex2));
                    [app.SMerge, app.fsMerge] = merged(app, currentSong1, fs1, currentSong2, fs2);
                    
                    info1 = audioinfo(app.fileNames(app.songIndex));
                    info2 = audioinfo(app.fileNames(app.songIndex2));
    
                    t1 = 0:seconds(1/fs1):seconds(info1.Duration);
                    t1 = t1(1:end-1);
                    t2 = 0:seconds(1/fs2):seconds(info2.Duration);
                    t2 = t2(1:end-1);
                           
                    app.UIAxes.YLim = [-1.5 1.5];
                    tiledlayout(2,1);
                       
                    plot(app.UIAxes_2,t1,currentSong1);
                    plot(app.UIAxes_3,t2,currentSong2); 
                end
            end
        end

        % Button pushed function: Button_2
        function Rewind(app, event)
            if app.repeatBool || app.shuffleBool
                return;
            end
            if app.songIndex ~= -1 
                [currentSong, fs] = audioread(app.fileNames(app.songIndex));
                app.player = audioplayer(currentSong, fs);
                app.Button.Text = "⏸";
                app.playOrPause = true;
                app.player.play;
            end
        end

        % Button pushed function: Button_3
        function Skip(app, event)
            if app.repeatBool || app.shuffleBool
                return;
            end
            if app.songIndex ~= -1
                app.songIndex = app.songIndex + 1;
                if app.songIndex > length(app.titles)
                    app.songIndex = mod(app.songIndex, length(app.titles));
                end

                channelID = 1940163;
                userAPIKey = 'AP3YO1V4OPUR9XK0';

               
                readKey = '7YYXWLHC6KULK76B';
                writeKey =  'E80CBWVDWY00LEOM';


                [currentSong, fs] = audioread(app.fileNames(app.songIndex));
                app.player = audioplayer(currentSong, fs);
                app.PickedSongLabel.Text = "Currently Playing: " + app.songTable(app.songIndex, 1).Variables;
                app.Button.Text = "⏸";
                app.playOrPause = true;
                app.player.play;
                
                pause(1)
                thingSpeakWrite(channelID, 'WriteKey', writeKey, 'Fields', [1], 'Values', app.songIndex);
                pause(1)

            end
        end

        % Value changed function: RepeatCheckBox
        function Repeat(app, event)
            if app.player == -3
                return;
            end
            app.repeatBool = true;  
            app.ShuffleCheckBox.Visible = "off";
            while app.repeatBool
                if app.player.isplaying == false
                    [currentSong, fs] = audioread(app.fileNames(app.songIndex));
                    app.player = audioplayer(currentSong, fs);
                    app.Button.Text = "⏸";
                    app.playOrPause = true;
                    app.player.play;
                    app.lastIndex = app.songIndex;
                end
                app.repeatBool = app.RepeatCheckBox.Value;
                if ~app.repeatBool
                    app.ShuffleCheckBox.Visible = "on";
                    break;
                end
                pause(1);
            end
        end

        % Value changed function: ShuffleCheckBox
        function Shuffle(app, event)
            if app.player == -3
                return;
            end
            app.shuffleBool = true;  
            app.RepeatCheckBox.Visible = "off";
            while app.shuffleBool
                if app.player.isplaying == false
                    app.PickedSongLabel.Text = "Currently Playing: " + app.songTable(app.songIndex, 1).Variables;
                    [currentSong, fs] = audioread(app.fileNames(app.songIndex));
                    app.player = audioplayer(currentSong, fs);
                    app.Button.Text = "⏸";
                    app.playOrPause = true;
                    app.player.play;
                    app.lastIndex = app.songIndex;
                end
                app.shuffleBool = app.ShuffleCheckBox.Value;
                if ~app.shuffleBool
                    app.RepeatCheckBox.Visible = "on";
                    app.PickedSongLabel.Text = "Currently Playing: " + app.songTable(app.songIndex, 1).Variables;
                    break;
                end
                while app.lastIndex == app.songIndex
                    app.songIndex = randi([1, length(app.titles)]);
                end
                pause(1);
            end
        end

        % Value changed function: PlaybackSpedSlider
        function PlayBackSpeed(app, event)
            app.playbackSpeed = round(2 * app.PlaybackSpedSlider.Value) / 2;
            if app.songIndex>0
                if app.checkMerge
                    if app.songIndex2  > 0
                        if app.songIndex > 0
                            [currentSong1, fs1] = audioread(app.fileNames(app.songIndex));
                            [currentSong2, fs2] = audioread(app.fileNames(app.songIndex2));
                            [app.currentSong, app.fs] = merged(app, currentSong1, fs1, currentSong2, fs2);
                        end
                    end
                else
                    [app.currentSong, app.fs] = audioread(app.fileNames(app.songIndex));
                end
    
                currentSong = app.currentSong;
                fs = app.fs;
    
                switch app.playbackSpeed
                    case -1
                        currentSong = app.currentSong(end:-1:1);
                        fs = app.fs;
    
                    case 0.5
                        fs = 0.5 * app.fs;
    
                    case 1
                        [currentSong, fs] = audioread(app.fileNames(app.songIndex));
                        fs = app.fs;
            
                    case 1.5
                        fs = 1.5 * app.fs;
                        
                    case 2
                        fs = 2 * app.fs;
                end
                app.player = audioplayer(currentSong, fs);
            end
        end

        % Value changed function: Merge
        function MergeValueChanged(app, event)
           app.checkMerge = app.Merge.Value;

           if app.checkMerge
               app.merge2.Visible = "on";
               app.UIAxes.Visible = "off";
               app.UIAxes_2.Visible = "on";
               app.UIAxes_3.Visible = "on";
               cla(app.UIAxes);
           else
               app.merge2.Visible = "off";
               app.UIAxes.Visible = "on";
               app.UIAxes_2.Visible = "off";
               app.UIAxes_3.Visible = "off";
               cla(app.UIAxes_2);
               cla(app.UIAxes_3);

           end
           
            
        end

        % Value changed function: VolumeKnob
        function VolumeKnobValueChanged(app, event)
            app.volume = app.VolumeKnob.Value;
            
            if app.checkMerge
                if app.songIndex2  > 0
                    if app.songIndex > 0
                        [currentSong1, fs1] = audioread(app.fileNames(app.songIndex));
                        [currentSong2, fs2] = audioread(app.fileNames(app.songIndex2));
                        [app.currentSong, app.fs] = merged(app, currentSong1, fs1, currentSong2, fs2);
                    end
                end
            elseif app.songIndex > 0
                [app.currentSong, app.fs] = audioread(app.fileNames(app.songIndex));
            end
            
            if app.fs > 0
                app.player = audioplayer(app.currentSong * app.volume, app.fs);
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio Visualization')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Audio Visualization Song 1')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Audio Visualization Song 2')
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'Amplitude')
            zlabel(app.UIAxes_3, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Select a Song Below'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @pickedSong, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @PlayStatus, true);
            app.Button.Text = '▶';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Skip, true);
            app.Button_3.Text = '»';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Rewind, true);
            app.Button_2.Text = '«';
            app.PickedSongLabel = uilabel(app.UIFigure);
            app.PickedSongLabel.Text = 'Picked Song:';
            app.RepeatCheckBox = uicheckbox(app.UIFigure);
            app.RepeatCheckBox.ValueChangedFcn = createCallbackFcn(app, @Repeat, true);
            app.RepeatCheckBox.Text = 'Repeat';
            app.ShuffleCheckBox = uicheckbox(app.UIFigure);
            app.ShuffleCheckBox.ValueChangedFcn = createCallbackFcn(app, @Shuffle, true);
            app.ShuffleCheckBox.Text = 'Shuffle';
            app.PlaybackSpedSliderLabel = uilabel(app.UIFigure);
            app.PlaybackSpedSliderLabel.Text = 'Playback Sped:';
            app.PlaybackSpedSlider = uislider(app.UIFigure);
            app.PlaybackSpedSlider.Limits = [-1 2];
            app.PlaybackSpedSlider.MajorTicks = [-1 0.5 1 1.5 2];
            app.PlaybackSpedSlider.ValueChangedFcn = createCallbackFcn(app, @PlayBackSpeed, true);
            app.PlaybackSpedSlider.MinorTicks = [];
            app.merge2 = uitable(app.UIFigure);
            app.merge2.ColumnName = {'Select a Song Below'};
            app.merge2.RowName = {};
            app.merge2.CellSelectionCallback = createCallbackFcn(app, @merge2CellSelection, true);
            app.Merge = uicheckbox(app.UIFigure);
            app.Merge.ValueChangedFcn = createCallbackFcn(app, @MergeValueChanged, true);
            app.Merge.Text = 'Merge';
            app.VolumeKnobLabel = uilabel(app.UIFigure);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.UIFigure, 'continuous');
            app.VolumeKnob.Limits = [0 1];
            app.VolumeKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.VolumeKnob.MajorTickLabels = {'0', '20%', '40%', '60%', '80%', '100%'};
            app.VolumeKnob.ValueChangedFcn = createCallbackFcn(app, @VolumeKnobValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioApp_AryanVarunYuvraj

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end